classdef MRScoreg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MRScoregUIFigure                matlab.ui.Figure
        SelectDirectory                 matlab.ui.control.Image
        WorkingDirectoryLabel           matlab.ui.control.Label
        Step4AverageimagesforMRSslicesPanel  matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        x1y1zLabel                      matlab.ui.control.Label
        ImagetypeLabel                  matlab.ui.control.Label
        CreateSlicessbyaveragingButton_2  matlab.ui.control.Button
        TypeTextAreaLabel               matlab.ui.control.Label
        ImageLabel                      matlab.ui.control.Label
        SpectroscopyLabel               matlab.ui.control.Label
        GridconfigurationTextAreaLabel  matlab.ui.control.Label
        SelectcolorColorPicker          matlab.ui.control.ColorPicker
        SelectcolorColorPickerLabel     matlab.ui.control.Label
        AddMRSgridtoimagesCheckBox      matlab.ui.control.CheckBox
        SummaryinformationLabel         matlab.ui.control.Label
        MatchesMaskTable3               matlab.ui.control.Table
        Step3SegmentimagesPanel         matlab.ui.container.Panel
        CreateSlicessButton             matlab.ui.control.Button
        MatchesMaskTable2               matlab.ui.control.Table
        Step2CreatesegmentationmasksPanel  matlab.ui.container.Panel
        CreateMasksButton               matlab.ui.control.Button
        MatchesMaskTable                matlab.ui.control.Table
        Step1MatchimageandspectroscopyfilesPanel  matlab.ui.container.Panel
        FindmatchesButton               matlab.ui.control.Button
        LoadMRS                         matlab.ui.control.Button
        ListMRS                         matlab.ui.control.ListBox
        SelectMRSFilesLabel             matlab.ui.control.Label
        LoadMRI                         matlab.ui.control.Button
        ListMRI                         matlab.ui.control.ListBox
        SelectMRIFilesLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        ConfigPath = 'config.txt'
        ITKPath = ''
        %SPM12Path = ''
        slices_output_dir =''
        workingDirectory
        mriMetadata 
        mrsMetadata
        maskPath
        imagePath
        matches
        matches2
        matches3
        GridEnabled = false;
        GridColor = [1 0 0];
        row
        col
        slice
        tiltData
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, directoryPath)

            app.MRScoregUIFigure.WindowState = 'maximized';
            % disp(['Number of arguments: ' num2str(nargin)]);

            % Configurationa
            clc;
            if isdeployed
                appName = mfilename('class');
                [appPath, ~, ~] = fileparts(which(appName));
                configPath = fullfile(appPath, app.ConfigPath);
            else
                % For development - config file is in project folder
                configPath = app.ConfigPath;
            end
            
            
            config = loadConfigFile(configPath);
            
            if config.success
                disp('Configuration loaded successfully.')
                
           
                % Check each field
                if isfield(config, 'ITK')
                    app.ITKPath = config.ITK;
                else
                    fprintf('ITK field not found in config\n');
                end
                
                % if isfield(config, 'SPM12')
                %     app.SPM12Path = config.SPM12;
                %     setupSPM12(app.SPM12Path);
                % else
                %     fprintf('SPM12 field not found in config\n');
                % end
                
                % Now check app properties
                if ~isempty(app.ITKPath)
                    fprintf('ITK Path: %s\n', app.ITKPath);
                else
                    fprintf('app.ITKPath is empty\n');
                end
                
                % if ~isempty(app.SPM12Path)
                %     fprintf('SPM12 Path: %s\n', app.SPM12Path);
                % else
                %     fprintf('app.SPM12Path is empty\n');
                % end
                % 
            else
                fprintf('Config failed: %s\n', config.message);
                if isprop(app, 'UIFigure')
                    uialert(app.MRScoregUIFigure, ...
                        ['Configuration Error: ' newline config.message newline newline ...
                        'Please edit config.txt in the application directory.'], ...
                        'Configuration Error');
                end
            end
            

            if nargin > 1 && ~isempty(directoryPath) % If directory was passed
                disp(['Received directory: ' directoryPath]);
                app.workingDirectory = directoryPath;
                cd(app.workingDirectory);
                app.WorkingDirectoryLabel.Text = ['Working Directory: ' app.workingDirectory];
            else
                 if isdeployed
                    defaultDir = fullfile(getenv('USERPROFILE'), 'Documents', 'MRScoreg');
                    
                    % Create it if it doesn't exist
                    if ~exist(defaultDir, 'dir')
                        mkdir(defaultDir);
                    end
                    
                    app.workingDirectory = defaultDir;
                else
                    app.workingDirectory = pwd; % Dev mode - pwd is fine
                end
                
                fprintf('Setting output directory as default: %s \n', app.workingDirectory);
                cd(app.workingDirectory);
                app.WorkingDirectoryLabel.Text = ['Working Directory: ' app.workingDirectory];
            end
            

            %Clear List Box 
            app.ListMRI.Items = {};
            app.ListMRI.Value = {};
            app.ListMRI.ItemsData = {};
            app.ListMRS.Items = {};
            app.ListMRS.Value = {};
            app.ListMRS.ItemsData = {};
            app.mriMetadata = {};
            app.mrsMetadata = {};
            app.matches = {};
            app.matches2 = {};
            

        end

        % Button pushed function: LoadMRI
        function LoadMRIButtonPushed(app, event)
            app.ListMRI.Items = {};
            app.ListMRI.Value = {};
            app.ListMRI.ItemsData = {};
            
            % Initialize metadata storage
            app.mriMetadata = {};

            
            [mriFiles, pathname] = uigetfile('*.*', 'Select MRI files', 'MultiSelect', 'on');
            if isequal(mriFiles, 0), return; end
            if ischar(mriFiles), mriFiles = {mriFiles}; end
            
            fullPaths = cellfun(@(x) fullfile(pathname, x), mriFiles, 'UniformOutput', false);
            
            % Show progress dialog
            progressDlg = uiprogressdlg(app.MRScoregUIFigure, 'Title', 'Loading MRI files...', ...
                'Message', 'Reading DICOM metadata...', 'Indeterminate', 'on');
            
            startTime = tic;
            validFiles = {}; 
            validNames = {};
            validMetadata = {}; % Store metadata for valid files only
            
            fprintf('%s\n', repmat('-', 1, 30)); % 30 dashes
            disp('Loading image files....');
            fprintf('%s\n', repmat('-', 1, 30)); % 30 dashes
            
            try
                for i = 1:length(fullPaths)
                    progressDlg.Message = sprintf('Processing file %d of %d...', i, length(fullPaths));
                    drawnow;
                    
                    try
                        fprintf('Processing: %s\n', fullPaths{i}); % Debug
                        currentInfo = dicominfo(fullPaths{i});
                        
                        if isT1MRI(currentInfo)
                            [~, name, ext] = fileparts(fullPaths{i});
                            validFiles{end+1} = fullPaths{i};
                            validNames{end+1} = [name ext];
                            validMetadata{end+1} = currentInfo; % Store metadata
                            fprintf('Added as T1: %s\n', [name ext]); 
                            app.ImagetypeLabel.Text = ['T1 weighted image'];
                        else
                            fprintf('Not T1, skipped\n'); % Debug
                        end
                    catch ME
                        fprintf('Error processing %s: %s\n', fullPaths{i}, ME.message); % Show the actual error
                        continue;
                    end
                end
                
                fprintf('%s\n', repmat('-', 1, 30)); % 30 dashes
                fprintf('%d file(s) added.\n', length(validFiles));


                if ~isempty(validFiles)
                    progressDlg.Message = 'Adding files to list...';
                    drawnow;
                    app.ListMRI.Items = [app.ListMRI.Items; validNames'];
                    app.ListMRI.ItemsData = [app.ListMRI.ItemsData; validFiles'];
                    app.mriMetadata = validMetadata;
                end
                
                % Ensure dialog is visible for at least 1 second
                elapsedTime = toc(startTime);
                if elapsedTime < 1
                    pause(1 - elapsedTime);
                end
                
            catch ME
                close(progressDlg);
                rethrow(ME);
            end
            
            close(progressDlg);


        end

        % Button pushed function: LoadMRS
        function LoadMRSButtonPushed(app, event)
        % Button pushed function: LoadMRS
                app.ListMRS.Items = {};
                app.ListMRS.Value = {};
                app.ListMRS.ItemsData = {};
                
                % Initialize metadata storage
                app.mrsMetadata = {};
                
                [mrsFiles, pathname] = uigetfile('*.*', 'Select MRS files', 'MultiSelect', 'on');
                if isequal(mrsFiles, 0), return; end
                if ischar(mrsFiles), mrsFiles = {mrsFiles}; end
                
                fullPaths = cellfun(@(x) fullfile(pathname, x), mrsFiles, 'UniformOutput', false);
                
                % Show progress dialog
                progressDlg = uiprogressdlg(app.MRScoregUIFigure, 'Title', 'Loading MRS files...', ...
                    'Message', 'Reading DICOM metadata...', 'Indeterminate', 'on');
                
                startTime = tic;
                validFiles = {}; 
                validNames = {};
                validMetadata = {}; % Store metadata for valid files only
                
                fprintf('%s\n', repmat('-', 1, 30)); % 30 dashes
                disp('Loading MRS files....');
                fprintf('%s\n', repmat('-', 1, 30)); % 30 dashes
                
                try
                    for i = 1:length(fullPaths)
                        progressDlg.Message = sprintf('Processing file %d of %d...', i, length(fullPaths));
                        drawnow;
                        
                        try
                            fprintf('Processing: %s\n', fullPaths{i}); % Debug
                            currentInfo = dicominfo(fullPaths{i});
                            
                            if isMRSpectroscopy(currentInfo)
                                [~, name, ext] = fileparts(fullPaths{i});
                                validFiles{end+1} = fullPaths{i};
                                validNames{end+1} = [name ext];
                                validMetadata{end+1} = currentInfo;
                                fprintf('Added as MRS: %s\n', [name ext]);
                                
                                try
                                    % Better extraction with error handling
                                    if isfield(currentInfo, 'Private_2001_1018')
                                        sliceValue = currentInfo.Private_2001_1018;
                                        if isstruct(sliceValue) && isfield(sliceValue, 'Item_1')
                                            app.slice = double(sliceValue.Item_1);
                                        else
                                            app.slice = double(sliceValue);
                                        end
                                    else
                                        app.slice = 1;
                                    end
                                    
                                    app.row = double(currentInfo.Rows);
                                    app.col = double(currentInfo.Columns);
                                    
                                    % Ensure they're scalar values
                                    if length(app.slice) > 1, app.slice = app.slice(1); end
                                    if length(app.row) > 1, app.row = app.row(1); end
                                    if length(app.col) > 1, app.col = app.col(1); end
                                    
                                catch paramError
                                    fprintf('Warning: Could not extract MRS parameters: %s\n', paramError.message);
                                    app.slice = 1;
                                    app.row = 1;
                                    app.col = 1;
                                end
                                
                                app.x1y1zLabel.Text = sprintf('%d×%d×%d (X×Y×Z)', app.col, app.row, app.slice);
                            else
                                fprintf('Not MRS, skipped\n'); % Debug
                            end
                            
                        catch ME
                            fprintf('Error processing %s: %s\n', fullPaths{i}, ME.message); % Show the actual error
                            continue;
                        end
                    end
            
                    fprintf('%s\n', repmat('-', 1, 30)); % 30 dashes
                    fprintf('%d file(s) added.\n', length(validFiles));
            
                    if ~isempty(validFiles)
                        progressDlg.Message = 'Adding files to list...';
                        drawnow;
                        app.ListMRS.Items = [app.ListMRS.Items; validNames'];
                        app.ListMRS.ItemsData = [app.ListMRS.ItemsData; validFiles'];
                        app.mrsMetadata = validMetadata; % Store all metadata in app property
                    end
                    
                    % Ensure dialog is visible for at least 1 second
                    elapsedTime = toc(startTime);
                    if elapsedTime < 1
                        pause(1 - elapsedTime);
                    end
                    
                catch ME
                    close(progressDlg);
                    rethrow(ME);
                end
                
                close(progressDlg);


        end

        % Button pushed function: FindmatchesButton
        function FindmatchesButtonPushed(app, event)
            mriFiles = app.ListMRI.ItemsData;
            mrsFiles = app.ListMRS.ItemsData;
            
            if isempty(mriFiles) || isempty(mrsFiles)
                uialert(app.MRScoregUIFigure, 'Please load both MRI and MRS files first.', 'Missing Files');
                return;
            end
            
            % Check if metadata is available
            if isempty(app.mriMetadata) || isempty(app.mrsMetadata)
                uialert(app.MRScoregUIFigure, 'Metadata not available. Please reload the files.', 'Missing Metadata');
                return;
            end
            
            app.MatchesMaskTable.ColumnName = {'MRI file(s)', 'MRS file(s)', 'Mask(s) created'}; %'MRI path(s)', 'MRSpath(s)'
            
            % Show progress dialog
            progressDlg = uiprogressdlg(app.MRScoregUIFigure, 'Title', 'Finding matches...', ...
                'Message', 'Matching files using pre-loaded metadata...', 'Indeterminate', 'on');
            
            try
                % Call the standalone function with metadata
                app.matches = findDicomMatches(mriFiles, app.mriMetadata, mrsFiles, app.mrsMetadata);
                
                if isempty(app.matches)
                    close(progressDlg);
                    uialert(app.MRScoregUIFigure, 'No matching files found based on StudyInstanceUID.', 'No Matches');
                    return;
                end
                
                % Update table with matches
                app.MatchesMaskTable.Data = app.matches;
                close(progressDlg);
                fprintf('%s\n', repmat('-', 1, 30));
                fprintf('Successfully matched %d file pairs\n', size(app.matches, 1));
                
            catch ME
                close(progressDlg);
                uialert(app.MRScoregUIFigure, sprintf('Error finding matches: %s', ME.message), 'Error');
            end
        end

        % Button pushed function: CreateMasksButton
        function CreateMasksButtonPushed(app, event)
                wd = char(app.workingDirectory);
                mask_outpt_dir = char(fullfile(wd,'Masks'));
                nifti_outpt_dir = char(fullfile(wd,'NIFTI_Files'));
                
                if ~exist(mask_outpt_dir, 'dir')
                    mkdir(mask_outpt_dir)
                end
                if ~exist(nifti_outpt_dir, 'dir')
                    mkdir(nifti_outpt_dir)
                end
                
                % Initialize the mask status column to "Processing..." for all rows
                for i = 1:size(app.matches, 1)
                    app.matches{i, 3} = 'Processing...';
                end
                app.MatchesMaskTable.Data = app.matches;
                drawnow; % Force UI update
                
                % Process each match
                for i = 1:size(app.matches, 1)
                    mriFilename = app.matches{i, 1};
                    mrsFilename = app.matches{i, 2};
                    
                    fprintf('%s\n', repmat('-', 1, 30)); % 30 dashes
                    fprintf('Processing match %d \n', i);
                    % fprintf('MRI: %s\n', mriFilename);
                    % fprintf('MRS: %s\n', mrsFilename);
                    
                    try
                        % Find full paths
                        mriFullPath = char(findPathByFilename(mriFilename, app.ListMRI.ItemsData));
                        mrsFullPath = char(findPathByFilename(mrsFilename, app.ListMRS.ItemsData));
                        
                        fprintf('MRI path: %s (exists: %d)\n', mriFullPath, exist(mriFullPath, 'file'));
                        fprintf('MRS path: %s (exists: %d)\n', mrsFullPath, exist(mrsFullPath, 'file'));
                        
                        % Convert MRI to NIfTI
                        % mriNifti = convertDicomToNifti(mriFullPath, nifti_outpt_dir);
                        mriNifti = convertDicomToNifti(mriFullPath, nifti_outpt_dir, app.ITKPath);
                 
                        mriNifti = char(mriNifti);
                        % fprintf('NIfTI: %s (exists: %d)\n', mriNifti, exist(mriNifti, 'file'));


                        % Create mask
                        if exist(mriNifti, 'file') && exist(mrsFullPath, 'file')
                            
                            % Update status to show current processing step
                            app.matches{i, 3} = 'Creating mask...';
                            app.MatchesMaskTable.Data = app.matches;
                            drawnow;
                            
                            % Suppress the matrix singularity warnings
                            warning('off', 'MATLAB:nearlySingularMatrix');
                            warning('off', 'MATLAB:singularMatrix');
                            
                            % Call createMask function
                            [~, fName_IM, ~] = fileparts(mriFullPath);
                            app.maskPath = createMask(mrsFullPath,fName_IM, mriNifti, mask_outpt_dir);
                          
                            % Re-enable warnings
                            warning('on', 'MATLAB:nearlySingularMatrix');
                            warning('on', 'MATLAB:singularMatrix');
                            
                            % Update table with success status
                            app.matches{i, 3} = '✓';
                            % fprintf('Successfully processed match %d\n', i);


                        else
                            % Update table with missing files status
                            app.matches{i, 3} = 'X';
                            fprintf('Missing files for match %d\n', i);
                        end
                        
                    catch ME
                        % Update table with error status
                        app.matches{i, 3} = sprintf('Error: %s', ME.message);
                        fprintf('✗ Error processing match %d: %s\n', i, ME.message);
                    end
                    
                    %  Update the table display after each row
                    app.MatchesMaskTable.Data = app.matches;
                    drawnow; % Force UI update to show progress
                    
                    % Small pause to ensure UI updates properly
                    pause(0.1);
                end
                
                fprintf('%s\n', repmat('-', 1, 30)); % 30 dashes
                fprintf('%d masks created\n', i);
                fprintf('%s\n', repmat('-', 1, 30)); % 30 dashes
                
        end

        % Button pushed function: CreateSlicessButton
        function CreateSlicessButtonPushed(app, event)
                wd = char(app.workingDirectory);

                app.slices_output_dir = char(fullfile(wd, 'Segmented images'));
                if ~exist(app.slices_output_dir, 'dir')
                    mkdir(app.slices_output_dir);
                end
                
                % Get successful masks from previous step
                originalData = app.MatchesMaskTable.Data;
                successfulMasks = strcmp(originalData(:, 3), '✓');
                filteredData = originalData(successfulMasks, :);
                
                if isempty(filteredData)
                    fprintf('No successful masks found to process for slices.\n');
                    return;
                end
                
                % Initialize matches2 data
                app.matches2 = filteredData;
                app.matches2(:, 3) = {'Processing...'};
                app.MatchesMaskTable2.Data = app.matches2;
                drawnow;
                
                % Process each successful mask
                for i = 1:size(app.matches2, 1)
                    try
                        mriFilename = app.matches2{i, 1};
                        fprintf('%s\n', repmat('-', 1, 30));
                        fprintf('Segmenting MRI image %d/%d: %s\n', i, size(app.matches2, 1), mriFilename);
                        
                        % File paths
                        % niftiPath = fullfile(wd, 'NIFTI_Files', [mriFilename '.nii']);
                        % maskPath = fullfile(wd, 'Masks', [mriFilename '-mask.nii']);
                        [~, baseImageName, ~] = fileparts(mriFilename);
                        niftiPath = fullfile(wd, 'NIFTI_Files', [baseImageName '.nii']);
                        maskPath = fullfile(wd, 'Masks', [baseImageName '-mask.nii']);
                        
                        % Debug output
                        fprintf(' NIfTI: %s (exists: %d)\n', niftiPath, exist(niftiPath, 'file'));
                        fprintf(' Mask: %s (exists: %d)\n', maskPath, exist(maskPath, 'file'));
                        
                        % Verify files exist
                        if ~exist(niftiPath, 'file')
                            error('NIfTI file does not exist: %s', niftiPath);
                        end
                        if ~exist(maskPath, 'file')
                            error('Mask file does not exist: %s', maskPath);
                        end
                        
                        % Update status and process
                        app.matches2{i, 3} = 'Segmenging image...';
                        app.MatchesMaskTable2.Data = app.matches2;
                        drawnow;
                        
                        % Call getSlices function
                        getSlices(niftiPath, maskPath, app.slices_output_dir);
                        
                        % Mark as successful
                        app.matches2{i, 3} = '✓';
                        fprintf('\nSuccessfully processed: %s\n', mriFilename);
                        
                    catch ME
                        % IMPORTANT: Update table with error status
                        app.matches2{i, 3} = sprintf('Error: %s', ME.message);
                        fprintf('Error processing match %d (%s): %s\n', i, mriFilename, ME.message);
                    end
                    
                    % Update table after each iteration (success or failure)
                    app.MatchesMaskTable2.Data = app.matches2;
                    drawnow;
                end
                fprintf('%s\n', repmat('-', 1, 30)); % 30 dashes
                fprintf('Segmentation completed.\n');
            
        end

        % Image clicked function: SelectDirectory
        function SelectDirectoryClicked(app, event)
            selectedPath = uigetdir(pwd, 'Select Directory');
    
            if selectedPath ~= 0  % User didn't cancel
                app.workingDirectory = selectedPath;
                app.WorkingDirectoryLabel.Text = ['Working Directory: ' selectedPath];
            end
        end

        % Button pushed function: CreateSlicessbyaveragingButton_2
        function CreateSlicessbyaveragingButton_2Pushed(app, event)

            wd = char(app.workingDirectory);
            app.slices_output_dir = char(fullfile(wd, 'Segmented images'));
            
            if ~exist(app.slices_output_dir, 'dir')
                mkdir(app.slices_output_dir);
            end

            mrs_output_dir = char(fullfile(wd, 'MRS images'));
            
            if ~exist(mrs_output_dir, 'dir')
                mkdir(mrs_output_dir);
            end
            
            % Get successful masks from previous step
            originalData = app.MatchesMaskTable2.Data;
            successfulMasks = strcmp(originalData(:, 3), '✓');
            filteredData = originalData(successfulMasks, :);
            
            if isempty(filteredData)
                fprintf('No successful masks found to process for slices.\n');
                return;
            end
            
            % Initialize matches2 data
            app.matches3 = filteredData;
            app.matches3(:, 3) = {'Processing...'};
            app.MatchesMaskTable3.Data = app.matches3;
            drawnow;

            %Grid status
            if app.GridEnabled
                fprintf('Grid selected\n');
                app.GridColor;
            end
            
             % Process each successful mask
            for i = 1:size(app.matches3, 1)
                try
                    mriFilename = app.matches3{i, 1};
                    
                     % Update status and process
                    app.matches3{i, 3} = 'Averaging image...';
                    app.MatchesMaskTable3.Data = app.matches3;
                    drawnow;

                    fprintf('%s\n', repmat('-', 1, 30));
                    fprintf('Averaging MRI image %d/%d: %s\n', i, size(app.matches3, 1), mriFilename);

                    allDir = fullfile(app.slices_output_dir , mriFilename, 'All');
                    fprintf('   Image directory: %s\n', allDir)
                    if ~exist(allDir, 'dir')
                        mkdir(allDir)
                        fprintf('\nDirectory created: %s', allDir)
                    end

                    outF = fullfile(app.slices_output_dir , mriFilename);


                    averageImages(allDir,app.slice,outF,mrs_output_dir, app.GridEnabled, app.GridColor, app.row, app.col);

                    % verageImages(slicedir,numberOfSlices,outF,outAV,GridEnabled,GridColor,row, col)
                    
                    % Mark as successful
                    app.matches3{i, 3} = '✓';
                    fprintf('Successfully processed: %s\n', mriFilename);
                    drawnow;
                catch ME
                        % IMPORTANT: Update table with error status
                        app.matches3{i, 3} = sprintf('Error: %s', ME.message);
                        fprintf('Error processing match %d (%s): %s\n', i, mriFilename, ME.message);
                        drawnow;
                        
                end
            end
            app.MatchesMaskTable3.Data = app.matches3;
            drawnow;
 
            fprintf('%s\n', repmat('-', 1, 30)); % 30 dashes
            fprintf('Averaging done.\n');

        end

        % Callback function: AddMRSgridtoimagesCheckBox, 
        % ...and 1 other component
        function AddMRSgridtoimagesCheckBoxValueChanged(app, event)
            app.GridEnabled = app.AddMRSgridtoimagesCheckBox.Value;
                if app.GridEnabled
                    app.GridColor = app.SelectcolorColorPicker.Value;
                end
            
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            configApp = configgrid();

            % Set current values if they exist -
            if ~isempty(app.row) && isnumeric(app.row) && app.row(1) > 0
                rowValue = double(app.row(1));  % Take first element and convert to double
                configApp.RowsXEditField.Value = rowValue;
                configApp.row = rowValue;
            end
            if ~isempty(app.col) && isnumeric(app.col) && app.col(1) > 0
                colValue = double(app.col(1));  % Take first element and convert to double
                configApp.ColumnsYEditField.Value = colValue;
                configApp.col = colValue;
            end
            if ~isempty(app.slice) && isnumeric(app.slice) && app.slice(1) > 0
                sliceValue = double(app.slice(1));  % Take first element and convert to double
                configApp.SlicesZEditField.Value = sliceValue;
                configApp.slice = sliceValue;
            end
            
            % Wait for dialog to close
            uiwait(configApp.GridconfigurationUIFigure);
            
            % Check if dialog was closed with OK (not cancelled)
            if isvalid(configApp) && isvalid(configApp.GridconfigurationUIFigure)
                % Update main app values
                app.row = configApp.row;
                app.col = configApp.col;
                app.slice = configApp.slice;
                
                % Update the label
                updateLabel(app.row, app.col, app.slice, app.x1y1zLabel);
                
                % Clean up
                delete(configApp);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MRScoregUIFigure = uifigure('Visible', 'off');
            app.MRScoregUIFigure.Name = 'MRScoreg';
            app.MRScoregUIFigure.Icon = fullfile(pathToMLAPP, 'MRScoregIcon5.png');
            app.Step1MatchimageandspectroscopyfilesPanel = uipanel(app.MRScoregUIFigure);
            app.Step1MatchimageandspectroscopyfilesPanel.Title = 'Step 1: Match image and spectroscopy file(s)';
            app.SelectMRIFilesLabel = uilabel(app.Step1MatchimageandspectroscopyfilesPanel);
            app.SelectMRIFilesLabel.Text = '1. Select MRI File(s)';
            app.ListMRI = uilistbox(app.Step1MatchimageandspectroscopyfilesPanel);
            app.ListMRI.Items = {''};
            app.ListMRI.Value = '';
            app.LoadMRI = uibutton(app.Step1MatchimageandspectroscopyfilesPanel, 'push');
            app.LoadMRI.ButtonPushedFcn = createCallbackFcn(app, @LoadMRIButtonPushed, true);
            app.LoadMRI.Text = 'Load files';
            app.SelectMRSFilesLabel = uilabel(app.Step1MatchimageandspectroscopyfilesPanel);
            app.SelectMRSFilesLabel.Text = '2. Select MRS File(s)';
            app.ListMRS = uilistbox(app.Step1MatchimageandspectroscopyfilesPanel);
            app.ListMRS.Items = {''};
            app.ListMRS.Value = '';
            app.LoadMRS = uibutton(app.Step1MatchimageandspectroscopyfilesPanel, 'push');
            app.LoadMRS.ButtonPushedFcn = createCallbackFcn(app, @LoadMRSButtonPushed, true);
            app.LoadMRS.Text = 'Load files';
            app.FindmatchesButton = uibutton(app.Step1MatchimageandspectroscopyfilesPanel, 'push');
            app.FindmatchesButton.ButtonPushedFcn = createCallbackFcn(app, @FindmatchesButtonPushed, true);
            app.FindmatchesButton.Text = 'Find match(es)';
            app.Step2CreatesegmentationmasksPanel = uipanel(app.MRScoregUIFigure);
            app.Step2CreatesegmentationmasksPanel.Title = 'Step 2: Create segmentation mask(s)';
            app.MatchesMaskTable = uitable(app.Step2CreatesegmentationmasksPanel);
            app.MatchesMaskTable.ColumnName = {'MRI file(s)'; 'MRS file(s)'; 'Mask(s) created'};
            app.MatchesMaskTable.RowName = {};
            app.CreateMasksButton = uibutton(app.Step2CreatesegmentationmasksPanel, 'push');
            app.CreateMasksButton.ButtonPushedFcn = createCallbackFcn(app, @CreateMasksButtonPushed, true);
            app.CreateMasksButton.Text = 'Create Mask(s)';
            app.Step3SegmentimagesPanel = uipanel(app.MRScoregUIFigure);
            app.Step3SegmentimagesPanel.Title = 'Step 3: Segment image(s)';
            app.MatchesMaskTable2 = uitable(app.Step3SegmentimagesPanel);
            app.MatchesMaskTable2.ColumnName = {'MRI file(s)'; 'MRS file(s)'; 'Images created'};
            app.MatchesMaskTable2.RowName = {};
            app.CreateSlicessButton = uibutton(app.Step3SegmentimagesPanel, 'push');
            app.CreateSlicessButton.ButtonPushedFcn = createCallbackFcn(app, @CreateSlicessButtonPushed, true);
            app.CreateSlicessButton.Text = 'Create Slices(s)';
            app.Step4AverageimagesforMRSslicesPanel = uipanel(app.MRScoregUIFigure);
            app.Step4AverageimagesforMRSslicesPanel.Title = 'Step 4: Average image(s) for MRS slices';
            app.MatchesMaskTable3 = uitable(app.Step4AverageimagesforMRSslicesPanel);
            app.MatchesMaskTable3.ColumnName = {'MRI file(s)'; 'MRS file(s)'; 'Slice(s) created'};
            app.MatchesMaskTable3.RowName = {};
            app.SummaryinformationLabel = uilabel(app.Step4AverageimagesforMRSslicesPanel);
            app.SummaryinformationLabel.Text = 'Summary information';
            app.AddMRSgridtoimagesCheckBox = uicheckbox(app.Step4AverageimagesforMRSslicesPanel);
            app.AddMRSgridtoimagesCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddMRSgridtoimagesCheckBoxValueChanged, true);
            app.AddMRSgridtoimagesCheckBox.Text = 'Add MRS grid to images';
            app.SelectcolorColorPickerLabel = uilabel(app.Step4AverageimagesforMRSslicesPanel);
            app.SelectcolorColorPickerLabel.Text = 'Select color';
            app.SelectcolorColorPicker = uicolorpicker(app.Step4AverageimagesforMRSslicesPanel);
            app.SelectcolorColorPicker.Value = [1 1 1];
            app.SelectcolorColorPicker.ValueChangedFcn = createCallbackFcn(app, @AddMRSgridtoimagesCheckBoxValueChanged, true);
            app.GridconfigurationTextAreaLabel = uilabel(app.Step4AverageimagesforMRSslicesPanel);
            app.GridconfigurationTextAreaLabel.Text = 'Grid configuration';
            app.SpectroscopyLabel = uilabel(app.Step4AverageimagesforMRSslicesPanel);
            app.SpectroscopyLabel.Text = 'Spectroscopy';
            app.ImageLabel = uilabel(app.Step4AverageimagesforMRSslicesPanel);
            app.ImageLabel.Text = 'Image';
            app.TypeTextAreaLabel = uilabel(app.Step4AverageimagesforMRSslicesPanel);
            app.TypeTextAreaLabel.Text = 'Type';
            app.CreateSlicessbyaveragingButton_2 = uibutton(app.Step4AverageimagesforMRSslicesPanel, 'push');
            app.CreateSlicessbyaveragingButton_2.ButtonPushedFcn = createCallbackFcn(app, @CreateSlicessbyaveragingButton_2Pushed, true);
            app.CreateSlicessbyaveragingButton_2.Text = 'Create Slices(s) by averaging';
            app.ImagetypeLabel = uilabel(app.Step4AverageimagesforMRSslicesPanel);
            app.ImagetypeLabel.Text = 'Image type';
            app.x1y1zLabel = uilabel(app.Step4AverageimagesforMRSslicesPanel);
            app.x1y1zLabel.Text = '1x1y1z';
            app.Image = uiimage(app.Step4AverageimagesforMRSslicesPanel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'settings.png');
            app.WorkingDirectoryLabel = uilabel(app.MRScoregUIFigure);
            app.WorkingDirectoryLabel.Text = 'Label palceholder for path ';
            app.SelectDirectory = uiimage(app.MRScoregUIFigure);
            app.SelectDirectory.ImageClickedFcn = createCallbackFcn(app, @SelectDirectoryClicked, true);
            app.SelectDirectory.ImageSource = fullfile(pathToMLAPP, 'icons8-open-folder-50.png');
            % Show the figure after all components are created
            app.MRScoregUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MRScoreg(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MRScoregUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MRScoregUIFigure)
        end
    end
end