classdef configgrid < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GridconfigurationUIFigure  matlab.ui.Figure
        CancelButton               matlab.ui.control.Button
        OKButton                   matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        SlicesZEditField           matlab.ui.control.NumericEditField
        SlicesZEditFieldLabel      matlab.ui.control.Label
        ColumnsYEditField          matlab.ui.control.NumericEditField
        ColumnsYEditFieldLabel     matlab.ui.control.Label
        RowsXEditField             matlab.ui.control.NumericEditField
        RowsXEditFieldLabel        matlab.ui.control.Label
        GridconfigurationLabel     matlab.ui.control.Label
    end

    
    properties (Access = public)
         row = 1     % Number of rows (X dimension)
        col = 1     % Number of columns (Y dimension) 
        slice = 1   % Number of slices (Z dimension)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RowsXEditField
        function RowsXEditFieldValueChanged(app, event)
            value = app.RowsXEditField.Value;
            app.col = value;  % Store the value
            
        end

        % Value changed function: ColumnsYEditField
        function ColumnsYEditFieldValueChanged(app, event)
            value = app.ColumnsYEditField.Value;
            app.row = value;  % Store the value

            
        end

        % Value changed function: SlicesZEditField
        function SlicesZEditFieldValueChanged(app, event)
            value = app.SlicesZEditField.Value;
            app.slice = value;  % Store the value
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.GridconfigurationUIFigure.Visible = 'off';
            uiresume(app.GridconfigurationUIFigure);  
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app.GridconfigurationUIFigure); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GridconfigurationUIFigure = uifigure('Visible', 'off');
            app.GridconfigurationUIFigure.Name = 'Grid configuration';
            app.GridconfigurationUIFigure.Icon = fullfile(pathToMLAPP, 'MRScoregIcon5.png');
            app.GridconfigurationLabel = uilabel(app.GridconfigurationUIFigure);
            app.GridconfigurationLabel.Text = 'Grid configuration';
            app.RowsXEditFieldLabel = uilabel(app.GridconfigurationUIFigure);
            app.RowsXEditFieldLabel.Text = 'Rows (X)';
            app.RowsXEditField = uieditfield(app.GridconfigurationUIFigure, 'numeric');
            app.RowsXEditField.ValueChangedFcn = createCallbackFcn(app, @RowsXEditFieldValueChanged, true);
            app.ColumnsYEditFieldLabel = uilabel(app.GridconfigurationUIFigure);
            app.ColumnsYEditFieldLabel.Text = 'Columns (Y)';
            app.ColumnsYEditField = uieditfield(app.GridconfigurationUIFigure, 'numeric');
            app.ColumnsYEditField.ValueChangedFcn = createCallbackFcn(app, @ColumnsYEditFieldValueChanged, true);
            app.SlicesZEditFieldLabel = uilabel(app.GridconfigurationUIFigure);
            app.SlicesZEditFieldLabel.Text = 'Slices (Z)';
            app.SlicesZEditField = uieditfield(app.GridconfigurationUIFigure, 'numeric');
            app.SlicesZEditField.ValueChangedFcn = createCallbackFcn(app, @SlicesZEditFieldValueChanged, true);
            app.Image = uiimage(app.GridconfigurationUIFigure);
            app.Image.ImageSource = 'settings.png';
            app.OKButton = uibutton(app.GridconfigurationUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.GridconfigurationUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.GridconfigurationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = configgrid

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GridconfigurationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GridconfigurationUIFigure)
        end
    end
end