classdef directorySelection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ContinueButton               matlab.ui.control.Button
        SelectworkingdirectoryButtonGroup  matlab.ui.container.ButtonGroup
        DirectoryTextCurrent         matlab.ui.control.TextArea
        DirectoryTextSelected        matlab.ui.control.TextArea
        SelectdirectoryRadioButton   matlab.ui.control.RadioButton
        CurrentdirectoryRadioButton  matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        selectedDirectory
        matches
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DirectoryTextCurrent.Value = pwd;
            app.selectedDirectory = pwd; 
        end

        % Selection changed function: SelectworkingdirectoryButtonGroup
        function SelectworkingdirectoryButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectworkingdirectoryButtonGroup.SelectedObject;
    
            if selectedButton == app.CurrentdirectoryRadioButton
                % Use current directory
                app.selectedDirectory = pwd;  % Store in property
                app.DirectoryTextSelected.Value = pwd;  % Display in component
                
            elseif selectedButton == app.SelectdirectoryRadioButton
                % Open directory selection dialog
                selectedPath = uigetdir(pwd, 'Select Directory');
                
                if selectedPath ~= 0  % User selected a directory (didn't cancel)
                    app.selectedDirectory = selectedPath;  % Store in property
                    app.DirectoryTextSelected.Value = selectedPath;  % Display in component
                else
                    % User canceled - revert back to current directory option
                    app.SelectworkingdirectoryButtonGroup.SelectedObject = app.CurrentdirectoryRadioButton;
                    app.selectedDirectory = pwd;  % Store in property
                    app.DirectoryTextSelected.Value = pwd;  % Display in component
                end
            end
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            % Open the createMaskr app

            coregistrationApp = coregistration(app.selectedDirectory);
            pause(0.1);
            % Close directory selection
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectworkingdirectoryButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectworkingdirectoryButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectworkingdirectoryButtonGroupSelectionChanged, true);
            app.SelectworkingdirectoryButtonGroup.Title = 'Select working directory';
            app.CurrentdirectoryRadioButton = uiradiobutton(app.SelectworkingdirectoryButtonGroup);
            app.CurrentdirectoryRadioButton.Tag = 'CurrentdirectoryRadioButton';
            app.CurrentdirectoryRadioButton.Text = 'Current directory';
            app.CurrentdirectoryRadioButton.Value = true;
            app.SelectdirectoryRadioButton = uiradiobutton(app.SelectworkingdirectoryButtonGroup);
            app.SelectdirectoryRadioButton.Text = 'Select directory';
            app.DirectoryTextSelected = uitextarea(app.SelectworkingdirectoryButtonGroup);
            app.DirectoryTextSelected.Tag = 'DirectoryLabelSelected';
            app.DirectoryTextCurrent = uitextarea(app.SelectworkingdirectoryButtonGroup);
            app.DirectoryTextCurrent.Tag = 'DirectoryLabelCurrent';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = directorySelection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end